classdef dosimetria_hn_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        pnl_status          matlab.ui.container.Panel
        txt_status          matlab.ui.control.Label
        pnl_path            matlab.ui.container.Panel
        txt_path            matlab.ui.control.Label
        pnl_dosimetry       matlab.ui.container.Panel
        btn_showMCNPerror   matlab.ui.control.Button
        btn_utcp            matlab.ui.control.Button
        pnl_tir             matlab.ui.container.Panel
        btn_tir_point       matlab.ui.control.Button
        bnt_tir_region      matlab.ui.control.Button
        txt_tir             matlab.ui.control.Label
        inp_tir             matlab.ui.control.EditField
        btn_create_dvh      matlab.ui.control.Button
        pnl_rois            matlab.ui.container.Panel
        tbl_roi             matlab.ui.control.Table
        btn_isoe_dose       matlab.ui.control.Button
        btn_w_dose          matlab.ui.control.Button
        btn_abs_dose        matlab.ui.control.Button
        pnl_stack_explorer  matlab.ui.container.Panel
        btn_findr           matlab.ui.control.Button
        btn_findl           matlab.ui.control.Button
        btn_find            matlab.ui.control.Button
        txt_expZ            matlab.ui.control.Label
        txt_expY            matlab.ui.control.Label
        txt_expX            matlab.ui.control.Label
        btn_go              matlab.ui.control.Button
        inp_zcor            matlab.ui.control.EditField
        inp_ycor            matlab.ui.control.EditField
        inp_xcor            matlab.ui.control.EditField
        txt_nstack          matlab.ui.control.Label
        btn_rigth_arrow     matlab.ui.control.Button
        btn_left_arrow      matlab.ui.control.Button
        pnl_case            matlab.ui.container.Panel
        btn_save_case       matlab.ui.control.Button
        pnl_matrixrotation  matlab.ui.container.Panel
        txt_mirror          matlab.ui.control.Label
        txt_switch          matlab.ui.control.Label
        btn_sYZ             matlab.ui.control.Button
        btn_sZX             matlab.ui.control.Button
        btn_sXY             matlab.ui.control.Button
        btn_mY              matlab.ui.control.Button
        btn_mZ              matlab.ui.control.Button
        btn_mX              matlab.ui.control.Button
        pnl_1stfr           matlab.ui.container.Panel
        inp_1stb10          matlab.ui.control.EditField
        txt_1b10            matlab.ui.control.Label
        btn_load_1fr        matlab.ui.control.Button
        pnl_ctscan          matlab.ui.container.Panel
        txt_Y               matlab.ui.control.Label
        txt_Z               matlab.ui.control.Label
        txt_X               matlab.ui.control.Label
        txt_step            matlab.ui.control.Label
        txt_max             matlab.ui.control.Label
        txt_min             matlab.ui.control.Label
        inp_xstep           matlab.ui.control.EditField
        inp_ystep           matlab.ui.control.EditField
        inp_zstep           matlab.ui.control.EditField
        inp_zmax            matlab.ui.control.EditField
        inp_ymax            matlab.ui.control.EditField
        inp_xmax            matlab.ui.control.EditField
        inp_zmin            matlab.ui.control.EditField
        inp_ymin            matlab.ui.control.EditField
        inp_xmin            matlab.ui.control.EditField
        btn_load_images     matlab.ui.control.Button
        btn_new_case        matlab.ui.control.Button
        txt_patient_id      matlab.ui.control.Label
        btn_load_case       matlab.ui.control.Button
        pnl_2ndfr           matlab.ui.container.Panel
        inp_2ndb10          matlab.ui.control.EditField
        txt_2b10            matlab.ui.control.Label
        btn_load_2fr        matlab.ui.control.Button
        pnl_images          matlab.ui.container.Panel
        img                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit22_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit22 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('dosimetria_hn_App.mat');
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
            app.tbl_roi.BackgroundColor = [1 1 1];
            app.tbl_roi.ColumnFormat = {[] 'char' 'logical'};
            app.tbl_roi.Data = componentData.tbl_roi.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function dosimetria_hn_OpeningFcn(app, varargin)
            % --- Executes just before dosimetria_hn is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to dosimetria_hn (see VARARGIN)
            
            % Choose default command line output for dosimetria_hn
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: btn_abs_dose
        function btn_abs_dose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_abs_dose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            set(handles.txt_status,'String','Calculating Absorbed Dose...')
            
            patient.Dt.boro = patient.D1.boro*patient.boron1;
            
            
            prompt = {'Tissue/Blood Ratio:'};
            dlg_title = 'Absorbed Dose';
            num_lines = 1;
            defaultans = {'1'};
            answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            ratio = str2double(answ{1});
            
            % Calculo Dosis Fisica.
            patient.Df = ((ratio*patient.Dt.boro) + patient.Dt.fast + patient.Dt.thn + patient.Dt.g)*patient.tir;
            % patient.Df(patient.stack == 0) = NaN;
            % Calculo de las coordenadas del maximo de la dosis Fisica.
            [patient.xdmax,patient.ydmax,patient.zdmax] = ind2sub(size(patient.Df),find(patient.Df==max(max(max(patient.Df)))));
            patient.Dfmax = max(max(max(patient.Df)));
            patient.px = patient.xdmax(1);
            patient.py = patient.ydmax(1);
            patient.nimg = patient.zdmax(1);
            % Calculo NTCP.
            % Calculo las proporciones de las componentes respecto al punto elegido.


            patient.rb = ratio*patient.Dt.boro*patient.tir./patient.Df;
            patient.rf = patient.Dt.fast*patient.tir./patient.Df;
            patient.rt = patient.Dt.thn*patient.tir./patient.Df;
            patient.rg = patient.Dt.g*patient.tir./patient.Df;
            
             % Calculo NTCP
            parametros_prescription = model_selector(4);
            %[patient.Daf,patient.ntcp] = ntcp(patient.Df,patient.rb,patient.rf+patient.rt,patient.rg,parametros_ntcp);
            patient.Deq = isodose(patient.Dt,patient.tir,ratio,parametros_prescription(1:14));


            seleccion = get(handles.tbl_roi,'Data');
            seleccion=seleccion(:,3);
            %seleccion=cell2mat(seleccion);
            seleccion = cellfun(@(x) double(x), seleccion);
            if sum(seleccion)==0
               warndlg('You must select an ROI before proceeding.', 'Advertencia');
               return
            end
            
            
            patient.dvh_roi=seleccion;
            rois = patient.roi_level(patient.dvh_roi == 1);
            mask = nan(size(patient.stack));
            rois_names = patient.roi_list(patient.dvh_roi == 1);
            
            
            mask = nan(size(patient.stack));
            for i=1:length(rois)
                mask(patient.stack==rois(i))=1;
            end
            Dmask = patient.Deq .* mask;
            dvh_dose = Dmask(mask==1);
            dv = patient.xstep * patient.ystep * patient.zstep;
            roi_vol = dv*length(dvh_dose);
            
            [patient.Def,patient.ntcp] = ntcp(dvh_dose, dv/roi_vol, parametros_prescription(15:18));
            
            %% hay que pasarlo al DVH
            %nNaN=sum(isnan(patient.Df(:)));
            %if nNaN>0
            %   warndlg('Se encontraron valores NaN en los datos', 'Advertencia');
            %end
            
            
            set(handles.rbtn_showDf,'Enable','off')
            set(handles.rbtn_showDf,'Value',1)
            
            patient.show = 1;
            if(isfield(patient,'himg'))
              patient = rmfield(patient,'himg');
            end
            if(isfield(patient,'dose_surf'))
              patient = rmfield(patient,'dose_surf');
            end
            if(isfield(patient,'hisoc'))
              patient = rmfield(patient,'hisoc');
            end
            if(isfield(patient,'hplotmax'))
              patient = rmfield(patient,'hplotmax');
            end
            ax=gca;
            cla(ax)
            ax.Toolbar.Visible = 'on';
            
            plot_choosen_dose(hObject,1)
            
            if(isfield(patient,'Dt'))
                set(handles.rbtn_showMCNPerror,'Enable','off')
                set(handles.rbtn_showMCNPerror,'Value',0)
            end
            
            if(isfield(patient,'Dw'))
                set(handles.rbtn_showDw,'Enable','off')
                set(handles.rbtn_showDw,'Value',0)
            end
            
            if(isfield(patient,'Diso'))
                set(handles.rbtn_showDiso,'Enable','off')
                set(handles.rbtn_showDiso,'Value',0)
            end
            
            set(handles.txt_status,'String','Calculating Absorbed Dose...OK')
            set(handles.btn_create_dvh,'Enable','on')
            set(handles.btn_utcp,'Enable','on')
            set(handles.btn_tir_point,'Enable','on')
            set(handles.btn_find,'Enable','on')
        end

        % Button pushed function: btn_create_dvh
        function btn_create_dvh_Callback(app, event)
            % --- Executes on button press in btn_create_dvh.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_create_dvh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            
            switch patient.show
                case 1
                    % Absorbed Dose.
                    Dose = patient.Df;
                    type = 'Absorbed Dose';
                    unit = ' Gy';
                case 2
                    % RBEw Dose.
                    Dose = patient.Dw;
                    type = 'RBEw Dose';
                    unit = ' Gy (RBE)';
                case 3
                    % IsoEffective Dose.
                    Dose = patient.Diso;
                    type = 'IsoEffective Dose';
                    unit = ' Gy (isoE)';
            end
            
            seleccion = get(handles.tbl_roi,'Data');
            seleccion=seleccion(:,3);
            %seleccion=cell2mat(seleccion);
            seleccion = cellfun(@(x) double(x), seleccion);
            if sum(seleccion)==0
               warndlg('You must select an ROI before proceeding.', 'Advertencia');
               return
            end
            patient.dvh_roi=seleccion;
            rois = patient.roi_level(patient.dvh_roi == 1);
            mask = nan(size(patient.stack));
            rois_names = patient.roi_list(patient.dvh_roi == 1);
            
            for i=1:length(rois)
                mask(patient.stack==rois(i))=1;
            end
            
            Dmask = Dose .* mask;
            dvh_dose = Dmask(mask==1);
            
            dv = patient.xstep * patient.ystep * patient.zstep;
            roi_vol = dv*length(dvh_dose);
            
            choice = questdlg('Select type of tissue:','Type of tissue','Normal Tissue','Tumor','Normal Tissue');
            
            switch choice
                case 'Normal Tissue'
                        %% Mai
                        parametros_ntcp = model_selector(3);
                        model_name = [parametros_ntcp(1)];
                        [patient.Deffect, patient.ntcp] = ntcp(dvh_dose,dv/roi_vol,[parametros_ntcp{2:5}]);


                        dvh_dose = [0;sort(dvh_dose,'ascend')];
            
                        ind=isnan(dvh_dose);
                        dvh_dose=dvh_dose(~ind);
            
                        dvh_vol = 100:-100/(length(dvh_dose)-1):0;
            
                        txt(1) = cellstr(strcat('ROIs: ',upper(strjoin(rois_names))));
                        txt(2) = cellstr(strcat('Vol = ',num2str(roi_vol,'%0.2f'), ' cm^3'));
                        txt(3) = cellstr(strcat('max = ',num2str(max(dvh_dose),'%0.2f'),unit));
                        txt(4) = cellstr(strcat('mean = ',num2str(mean(dvh_dose),'%0.2f'),unit));
                        txt(5) = cellstr(strcat('min = ',num2str(min(dvh_dose(2:end)),'%0.2f'),unit));
                        txt(6) = cellstr(strcat('EUD = ', num2str(patient.Deffect, '%0.2f'), unit));
                        txt(7) = cellstr(strcat('NTCP(', model_name, ') = ', num2str(patient.ntcp)));
            
                        dim = [.2 .5 .3 .3];
            
                        figure
                        hold on
                        h=plot(dvh_dose,dvh_vol);
                        xlim([0 max(dvh_dose)*1.05])
                        ylim([0 102])
                        xlabel(strcat(type,' [',unit,']'))
                        ylabel('Volume [%]')
                        annotation('textbox',dim,'String',txt,'FitBoxToText','on','BackgroundColor','w');
                        grid on
                        box on
                        hold off
            
            
                case 'Tumor'
                        parametros_tcp = model_selector(2);
            
                        % Mai
                        model_name = [parametros_tcp(1)];
                        [ patient.tcp, patient.PEUD] = TCPc(dvh_dose,dv,[parametros_tcp{2:11}]);
            
            
                        ind=isnan(dvh_dose);
                        dvh_dose=dvh_dose(~ind);
            
                        dvh_dose = [0;sort(dvh_dose,'ascend')];
                        dvh_vol = 100:-100/(length(dvh_dose)-1):0;
            
                        txt(1) = cellstr(strcat('ROIs: ',upper(strjoin(rois_names))));
                        txt(2) = cellstr(strcat('Vol = ',num2str(roi_vol,'%0.2f'), ' cm^3'));
                        txt(3) = cellstr(strcat('max = ',num2str(max(dvh_dose),'%0.2f'),unit));
                        txt(4) = cellstr(strcat('mean = ',num2str(mean(dvh_dose),'%0.2f'),unit));
                        txt(5) = cellstr(strcat('min = ',num2str(min(dvh_dose(2:end)),'%0.2f'),unit));
                        txt(6) = cellstr(strcat('TCP(', model_name, ') = ', num2str(patient.tcp)));
                        txt(7) = cellstr(strcat('PEUD(', model_name, ') = ', num2str(patient.PEUD),'Gy IsoE'));
            
                        dim = [.2 .5 .3 .3];
            
                        figure
                        hold on
                        h=plot(dvh_dose,dvh_vol);
                        xlim([0 max(dvh_dose)*1.05])
                        ylim([0 102])
                        xlabel(strcat(type,' [',unit,']'))
                        ylabel('Volumen[%]')
                        annotation('textbox',dim,'String',txt,'FitBoxToText','on','BackgroundColor','w');
                        grid on
                        box on
                        hold off
            end
            
            mensaje ="Do you want to calculate the irradiation time?";
            
            % Crear el cuadro de diálogo con los botones "Sí" y "No"
            resp= questdlg(mensaje, 'Calcular tir', 'Yes', 'No', 'No');
            
            
            if strcmp(resp, 'Yes')
               tir=f_prescripcion(h);
               patient.tir=tir;
               set(handles.inp_tir,'String',num2str(tir));
            end
        end

        % Button pushed function: btn_find
        function btn_find_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % --- Executes on button press in btn_find.
            % hObject    handle to btn_find (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            
            switch patient.show
                case 1
                    % Absorbed Dose.
                    Dose = patient.Df;
                    type = 'Absorbed Dose';
                    unit = ' Gy';
                    d = 'D=';
                case 2
                    % RBEw Dose.
                    Dose = patient.Dw;
                    type = 'RBEw Dose';
                    unit = ' Gy (RBE)';
                    d = 'Dw=';
                case 3
                    % IsoEffective Dose.
                    Dose = patient.Diso;
                    type = 'IsoEffective Dose';
                    unit = ' Gy (isoE)';
                    d = 'Diso=';
            end
            
            rois = patient.roi_level(patient.dvh_roi == 1);
            if(rois>0)
                mask = nan(size(patient.stack));
                %     rois_names = patient.roi_list(patient.dvh_roi == 1);
                for i=1:length(rois)
                    mask(patient.stack==rois(i))=1;
                end
            else
                mask = ones(size(patient.stack));
            end
            Dose = Dose .* mask;
            
            if(patient.show==1)
                prompt = {[type '[' unit ']'],'boron fraction:','neutron fraction:','gamma fraction:'};
                dlg_title = 'Find...';
                num_lines = 1;
                answ = inputdlg(prompt,dlg_title,num_lines);
            
                find_val = str2double(answ{1});
                find_fb = str2double(answ{2});
                find_fn = str2double(answ{3});
                find_fg = str2double(answ{4});
            
            
                txt = [d num2str(find_val,'%0.1f')];
                set(hObject,'String',txt);
            
                rb = patient.rb .* mask;
                rn = (patient.rf+patient.rt) .* mask;
                rg = patient.rg .* mask;
                %patient.find_matrix = abs(Dose-find_val);
                patient.find_matrix = sqrt(((Dose-find_val).^2)+((rb-find_fb).^2)+((rn-find_fn).^2)+((rg-find_fg).^2));
                patient.find_list = Dose(mask==1);
                patient.find_list = sort(reshape(patient.find_matrix,[1 numel(patient.find_matrix)]),'ascend');
                patient.find_ind = 1;
                [x,y,z] = ind2sub(size(patient.find_matrix),find(patient.find_matrix==patient.find_list(patient.find_ind )));
            
                set(handles.inp_xcor,'String',num2str(x));
                set(handles.inp_ycor,'String',num2str(y));
                set(handles.inp_zcor,'String',num2str(z));
            
                set(handles.btn_findl,'Enable','off');
                set(handles.btn_findr,'Enable','on');
            elseif(patient.show~=1)
                [x,y,z] = ind2sub(size(Dose),find(Dose==max(max(max(Dose)))));
                set(handles.inp_xcor,'String',num2str(x));
                set(handles.inp_ycor,'String',num2str(y));
                set(handles.inp_zcor,'String',num2str(z));
            end
        end

        % Button pushed function: btn_findl
        function btn_findl_Callback(app, event)
            % --- Executes on button press in btn_findl.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_findl (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            
            if(patient.find_ind==length(patient.find_list))
                set(handles.btn_findr,'Enable','on');
            end
            
            patient.find_ind = patient.find_ind - 1;
            
            [x,y,z] = ind2sub(size(patient.find_matrix),find(patient.find_matrix==patient.find_list(patient.find_ind )));
            
            set(handles.inp_xcor,'String',num2str(x));
            set(handles.inp_ycor,'String',num2str(y));
            set(handles.inp_zcor,'String',num2str(z));
            
            if(patient.find_ind==1)
                set(hObject,'Enable','off');
            end
        end

        % Button pushed function: btn_findr
        function btn_findr_Callback(app, event)
            % --- Executes on button press in btn_findr.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_findr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            
            if(patient.find_ind==1)
                set(handles.btn_findl,'Enable','on');
            end
            
            patient.find_ind = patient.find_ind + 1;
            
            [x,y,z] = ind2sub(size(patient.find_matrix),find(patient.find_matrix==patient.find_list(patient.find_ind )));
            
            set(handles.inp_xcor,'String',num2str(x));
            set(handles.inp_ycor,'String',num2str(y));
            set(handles.inp_zcor,'String',num2str(z));
            
            if(patient.find_ind==length(patient.find_list))
                set(hObject,'Enable','off');
            end
        end

        % Button pushed function: btn_go
        function btn_go_Callback(app, event)
            % --- Executes on button press in btn_go.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_go (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            patient.px = str2double(get(handles.inp_xcor,'String'));
            patient.py = str2double(get(handles.inp_ycor,'String'));
            patient.nimg = str2double(get(handles.inp_zcor,'String'));
            plot_choosen_dose(hObject,1)
        end

        % Button pushed function: btn_isoe_dose
        function btn_isoe_dose_Callback(app, event)
            % --- Executes on button press in btn_isoe_dose.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_isoe_dose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            set(handles.txt_status,'String','Calculating Iso Effective Dose...')
            
            prompt = {'Tumor/blood B10 ratio:'};
            dlgtitle = 'Tumor/blood B10 ratio';
            dims = [1 35];
            definput = {'3.5'};
            bratio = str2double(inputdlg(prompt,dlgtitle,dims,definput));
            
            patient.Dt.boro = patient.D1.boro*patient.boron1;
            
            % Calculo Dosis Isoefectiva.
            parametros = model_selector(1);
            patient.Diso = isodose(patient.Dt,patient.tir,bratio,parametros);
            % patient.Diso(patient.stack == 0) = NaN;
            
            % Calculo de las coordenadas del maximo de la dosis iso.
            [patient.xdmax,patient.ydmax,patient.zdmax] = ind2sub(size(patient.Diso),find(patient.Diso==max(max(max(patient.Diso)))));
            patient.Disomax = max(max(max(patient.Diso)));
            patient.px = patient.xdmax;
            patient.py = patient.ydmax;
            patient.nimg = patient.zdmax;
            
           % set(handles.rbtn_showDiso,'Enable','off')
           % set(handles.rbtn_showDiso,'Value',1)
            
            patient.show = 3;
            if(isfield(patient,'himg'))
              patient = rmfield(patient,'himg');
            end
            if(isfield(patient,'dose_surf'))
              patient = rmfield(patient,'dose_surf');
            end
            if(isfield(patient,'hisoc'))
              patient = rmfield(patient,'hisoc');
            end
            if(isfield(patient,'hplotmax'))
              patient = rmfield(patient,'hplotmax');
            end
            
            ax=gca;
            cla(ax)
            ax.Toolbar.Visible = 'on';
            
            plot_choosen_dose(hObject,1)
            
            if(isfield(patient,'Dt'))
                set(handles.rbtn_showMCNPerror,'Enable','off')
                set(handles.rbtn_showMCNPerror,'Value',0)
            end
            
            if(isfield(patient,'Dw'))
                set(handles.rbtn_showDw,'Enable','off')
                set(handles.rbtn_showDw,'Value',0)
            end
            
            if(isfield(patient,'Df'))
                set(handles.rbtn_showDf,'Enable','off')
                set(handles.rbtn_showDf,'Value',0)
            end
            
            set(handles.txt_status,'String','Calculating Iso Effective Dose...OK')
            set(handles.btn_create_dvh,'Enable','on')
            % set(handles.btn_utcp,'Enable','on')
            set(handles.btn_tir_point,'Enable','on')
            set(handles.btn_find,'Enable','on')
        end

        % Button pushed function: btn_left_arrow
        function btn_left_arrow_Callback(app, event)
            % --- Executes on button press in btn_left_arrow.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            if(patient.nimg>1)
                patient.nimg =patient.nimg-1;
                plot_choosen_dose(hObject,1);
            end
        end

        % Button pushed function: btn_load_1fr
        function btn_load_1fr_Callback(app, event)
            % --- Executes on button press in btn_load_1fr.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            set(handles.txt_status,'String','Loading Dose Matrix...')
            choice = questdlg('Select type of Dose Matrix:','Dose Matrix','Sera','Multicell','Sera');
            
            switch choice
                case 'Sera'
                    [filename, pathname] = uigetfile('*.dat','Select Sera Dose Matrix');
                    if isequal(filename,0)||isequal(pathname,0)
                    else
                        prompt = {'Neutrons:','Gamma'};
                        dlg_title = 'Fraction 1 - Scaling Factors:';
                        num_lines = 1;
                        answ = inputdlg(prompt,dlg_title,num_lines);
            
                        sf_boron = str2double(answ{1});
                        sf_g = str2double(answ{2});
            
                        patient.D1c = leer_sera(fullfile(pathname,filename),sf_g,sf_boron);
                        patient.D1c.E = zeros(size(patient.D1c.boro));
                    end
                case 'Multicell'
                    prompt = {'Thermal:','Fast:','Gamma:'};
                    dlg_title = 'Fraction 1 - Scaling Factors:';
                    num_lines = 1;
                    %defaultans = {'0.97','1.05','1.07'};
                    defaultans = {'1','1','1'};
                    answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
                    sthn = str2double(answ{1});
                    sfast = str2double(answ{2});
                    sg = str2double(answ{3});
            
                    choice = questdlg('Select type of MCNP output:','MCNP output','rssa (one file)','sdef (two files)', 'Dose .mat','rssa (one file)');
            
                    switch choice
                        case 'rssa (one file)'
                            [patient.D1c,patient.beam.n1,patient.beam.n2] = leer_multicell(sthn,sfast,sg);
            
                        case 'sdef (two files)'
                            patient.D1c = leer_multicell2(sthn,sfast,sg);
                        case 'Dose .mat' % agrear
                            tipoarchivo='.mat';
                            currentdirectory=pwd;
                            [archivo,directorio]=uigetfile(tipoarchivo,' Dose matrix');
                            if isequal(archivo,0)||isequal(directorio,0)
                                return
                            else
                                cd(directorio);
                                %file=dir(fullfile(directorio,tipoarchivo)); %carga la etrucuta de archivos
            
                                file=[directorio,archivo];
                                a=load(file);
                                patient.D1c=a.D1c;
                                clear a
                                cd(currentdirectory);
                            end
            
                    end
            end


            prompt = {'Blood B10 Concentration:'};
            dlg_title = 'Fraction 1';
            num_lines = 1;
            defaultans = {'15'};
            answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
            patient.boron1 = str2double(answ{1});
            
            set(handles.inp_1stb10,'Enable','on');
            set(handles.inp_1stb10,'String',num2str(patient.boron1));
            
            set(handles.btn_showMCNPerror,'Enable','on');
            set(handles.btn_abs_dose,'Enable','on');
            set(handles.btn_w_dose,'Enable','on');
            set(handles.btn_isoe_dose,'Enable','on');
            
             a=get(handles.inp_xmin);
             xmin=str2double(a.String);
             a=get(handles.inp_ymin);
             ymin=str2double(a.String);
             a=get(handles.inp_zmin);
             zmin=str2double(a.String);
            
             a=get(handles.inp_xmax);
             xmax=str2double(a.String);
             a=get(handles.inp_ymax);
             ymax=str2double(a.String);
             a=get(handles.inp_zmax);
             zmax=str2double(a.String);


            a=get(handles.inp_xstep);
            xstep=str2double(a.String);
            a=get(handles.inp_ystep);
            ystep=str2double(a.String);
            a=get(handles.inp_zstep);
            zstep=str2double(a.String);
            
            xmp = xstep/2;
            ymp = ystep/2;
            zmp = zstep/2;
            
            x = xmin+xmp:xstep:xmax-xmp;
            y = ymin+ymp:ystep:ymax-ymp;
            z = zmin+zmp:zstep:zmax-zmp;
            
            %x=patient.D1c.xc;
            %y=patient.D1c.yc;
            %z=patient.D1c.zc;
            
            %% agregar un chequeo para verificar que son igules
            
            
            [X,Y,Z] = ndgrid(x,y,z);
            [Xc,Yc,Zc] = ndgrid(patient.D1c.xc,patient.D1c.yc,patient.D1c.zc);
            
            % Interpolo componente a componente.
            set(handles.txt_status,'String','Interpolating...')
            
            
            %patient.D1c.boro=permute(patient.D1c.boro,[2,1,3]);
            
            patient.D1.boro=[];
            patient.D1.fast=[];
            patient.D1.thn=[];
            patient.D1.g=[];
            patient.D1.E=[];
            
            patient.D1.boro = interpn(Xc,Yc,Zc,patient.D1c.boro,X,Y,Z,'linear');
            patient.D1.fast = interpn(Xc,Yc,Zc,patient.D1c.fast.*0,X,Y,Z,'linear');
            patient.D1.thn = interpn(Xc,Yc,Zc,patient.D1c.thn,X,Y,Z,'linear');
            patient.D1.g = interpn(Xc,Yc,Zc,patient.D1c.g,X,Y,Z,'linear');
            for i=1:4
               patient.D1.E(:,:,:,i) = interpn(Xc,Yc,Zc,patient.D1c.E(:,:,:,i),X,Y,Z,'linear');
            end
            
            
            patient.Dt.boro=[];
            patient.Dt.fast=[];
            patient.Dt.thn=[];
            patient.Dt.g=[];
            patient.Dt.E=[];
            
            patient.Dt.boro=patient.D1.boro;
            patient.Dt.fast = patient.D1.fast;
            patient.Dt.thn = patient.D1.thn;
            patient.Dt.g = patient.D1.g;
            patient.Dt.E=patient.D1.E;
            
            patient.tir = 1;
            
            
            patient.xmin=xmin;
            patient.ymin=ymin;
            patient.zmin=zmin;
            patient.xmax=xmax;
            patient.ymax=ymax;
            patient.zmax=zmax;
            patient.xstep=xstep;
            patient.ystep=ystep;
            patient.zstep=zstep;


            set(handles.btn_mX,'Enable','on');
            set(handles.btn_mY,'Enable','on');
            set(handles.btn_mZ,'Enable','on');
            set(handles.btn_sXY,'Enable','on');
            set(handles.btn_sYZ,'Enable','on');
            set(handles.btn_sZX,'Enable','on');
            set(handles.btn_load_2fr,'Enable','off');
            set(handles.inp_tir,'Enable','on')
            set(handles.txt_status,'String','Dose Matrix...OK')
            
            set(handles.inp_xmin,'Enable','off');
            set(handles.inp_ymin,'Enable','off');
            set(handles.inp_zmin,'Enable','off');
            set(handles.inp_xmax,'Enable','off');
            set(handles.inp_ymax,'Enable','off');
            set(handles.inp_zmax,'Enable','off');
            set(handles.inp_xstep,'Enable','off');
            set(handles.inp_ystep,'Enable','off');
            set(handles.inp_zstep,'Enable','off');
            set(handles.btn_load_1fr,'Enable','off')
            set(handles.btn_load_images,'Enable','off')
            set(handles.inp_1stb10,'Enable','on')
        end

        % Button pushed function: btn_load_2fr
        function btn_load_2fr_Callback(app, event)
            % --- Executes on button press in btn_load_2fr.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_load_2fr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            set(handles.txt_status,'String','Loading Dose Matrix...')
            
            % Handle response
            if (~isfield(patient,'D2'))
                choice = questdlg('Select type of Dose Matrix:','Dose Matrix','Sera','Multicell','Sera');
                switch choice
                    case 'Sera'
                        [filename, pathname] = uigetfile('*.dat','Select Sera Dose Matrix');
                        if isequal(filename,0)||isequal(pathname,0)
                        else
                            prompt = {'Neutrons:','Gamma'};
                            dlg_title = 'Fraction 2 - Scaling Factors:';
                            num_lines = 1;
                            %defaultans = {'15'};
                            answ = inputdlg(prompt,dlg_title,num_lines);
            
                            sf_boron = str2double(answ{1});
                            sf_g = str2double(answ{2});
            
                            patient.D2c = leer_sera(fullfile(pathname,filename),sf_g,sf_boron);
                            patient.D2c.E = zeros(size(patient.D2c.boro));
                        end
                    case 'Multicell'
                        prompt = {'Thermal:','Fast:','Gamma:'};
                        dlg_title = 'Fraction 2 - Scaling Factors:';
                        num_lines = 1;
                        defaultans = {'0.97','1.05','1.07'};
                        answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
                        sthn = str2double(answ{1});
                        sfast = str2double(answ{2});
                        sg = str2double(answ{3});
            
                        patient.D2c = leer_multicell(sthn,sfast,sg);
                end
            
                prompt = {'Blood B10 Concentration:'};
                dlg_title = 'Fraction 2';
                num_lines = 1;
                defaultans = {'15'};
                answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
                patient.boron2 = str2double(answ{1});
            
                set(handles.inp_2ndb10,'Enable','on');
                set(handles.inp_2ndb10,'String',num2str(patient.boron2));
            
                xmp = patient.xstep/2;
                ymp = patient.ystep/2;
                zmp = patient.zstep/2;
            
                x=patient.xmin+xmp:patient.xstep:patient.xmax-xmp;
                y=patient.ymin+ymp:patient.ystep:patient.ymax-ymp;
                z=patient.zmin+zmp:patient.zstep:patient.zmax-zmp;
            
                [X,Y,Z] = ndgrid(x,y,z);
                [Xc,Yc,Zc]=ndgrid(patient.D2c.xc,patient.D2c.yc,patient.D2c.zc);
            
                % Interpolo componente a componente.
                set(handles.txt_status,'String','Interpolating...')
            
                patient.D2.boro = interpn(Xc,Yc,Zc,patient.D2c.boro,X,Y,Z,'linear');
                patient.D2.fast = interpn(Xc,Yc,Zc,patient.D2c.fast,X,Y,Z,'linear');
                patient.D2.thn = interpn(Xc,Yc,Zc,patient.D2c.thn,X,Y,Z,'linear');
                patient.D2.g = interpn(Xc,Yc,Zc,patient.D2c.g,X,Y,Z,'linear');
                patient.D2.E = interpn(Xc,Yc,Zc,patient.D2c.E,X,Y,Z,'linear');
            end
            
            prompt = {'Fraction 1 weight/time[min]:','Fraction 2 weight/time[min]:'};
            dlg_title = 'Fraction weights';
            num_lines = 1;
            defaultans = {'0.5','0.5'};
            answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
            fin1 = str2double(answ{1});
            fin2 = str2double(answ{2});
            
            patient.f1 = fin1/(fin1+fin2);
            patient.f2 = fin2/(fin1+fin2);
            
            txt = [num2str(patient.f1,'%0.2f') '*F1+' num2str(patient.f2,'%0.2f') '*F2'];
            set(hObject,'String',txt);
            
            patient.Dt.boro = (patient.f1*patient.D1.boro*patient.boron1)+(patient.f2*patient.D2.boro*patient.boron2);
            patient.Dt.fast = (patient.f1*patient.D1.fast)+(patient.f2*patient.D2.fast);
            patient.Dt.thn = (patient.f1*patient.D1.thn)+(patient.f2*patient.D2.thn);
            patient.Dt.g = (patient.f1*patient.D1.g)+(patient.f2*patient.D2.g);
            patient.Dt.E = patient.D2.E;
            
            set(handles.inp_tir,'Enable','on')
            set(handles.btn_showMCNPerror,'String','2nd BNCT Boron Relative Error')
            set(handles.txt_status,'String','Loading Dose Matrix...OK')
        end

        % Button pushed function: btn_load_case
        function btn_load_case_Callback(app, event)
            % btn_load_case: load existing patient case.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global patient;
            
            clc
            clear patient
            if ishandle(2);close(2);end
            if ishandle(3);close(3);end
            
            set(handles.inp_xmin,'Enable','off');
            set(handles.inp_ymin,'Enable','off');
            set(handles.inp_zmin,'Enable','off');
            set(handles.inp_xmax,'Enable','off');
            set(handles.inp_ymax,'Enable','off');
            set(handles.inp_zmax,'Enable','off');
            set(handles.inp_xstep,'Enable','off');
            set(handles.inp_ystep,'Enable','off');
            set(handles.inp_zstep,'Enable','off');
            set(handles.tbl_roi,'Visible','off');
            set(handles.btn_left_arrow,'Enable','off');
            set(handles.btn_rigth_arrow,'Enable','off');
            set(handles.txt_nstack,'Enable','off');
            set(handles.inp_xcor,'Enable','off');
            set(handles.inp_ycor,'Enable','off');
            set(handles.inp_zcor,'Enable','off');
            set(handles.btn_go,'Enable','off');
            set(handles.btn_load_1fr,'Enable','off');
            set(handles.btn_load_2fr,'Enable','off');
            set(handles.inp_tir,'Enable','off')
            set(handles.btn_showMCNPerror,'Enable','off');
            set(handles.btn_abs_dose,'Enable','off');
            set(handles.btn_w_dose,'Enable','off');
            set(handles.btn_isoe_dose,'Enable','off');
            %set(handles.pnl_matrixrotation,'Enable','off');
            set(handles.pnl_matrixrotation,'Enable','off');
            set(handles.btn_mX,'Enable','on');
            set(handles.btn_mY,'Enable','on');
            set(handles.btn_mZ,'Enable','on');
            set(handles.btn_sXY,'Enable','on');
            set(handles.btn_sYZ,'Enable','on');
            set(handles.btn_sZX,'Enable','on');
            set(handles.btn_create_dvh,'Enable','off');
            set(handles.btn_utcp,'Enable','off');
            set(handles.btn_tir_point,'Enable','off');
            %set(handles.btn_tir_region,'Enable','off');
            set(handles.inp_xmin,'String','');
            set(handles.inp_ymin,'String','');
            set(handles.inp_zmin,'String','');
            set(handles.inp_xmax,'String','');
            set(handles.inp_ymax,'String','');
            set(handles.inp_zmax,'String','');
            set(handles.inp_xstep,'String','');
            set(handles.inp_ystep,'String','');
            set(handles.inp_zstep,'String','');
            set(handles.inp_1stb10,'String','');
            set(handles.inp_tir,'String','1')
            set(handles.inp_xcor,'String','');
            set(handles.inp_ycor,'String','');
            set(handles.inp_zcor,'String','');
            set(handles.txt_nstack,'String','');
            %set(handles.rbtn_showMCNPerror,'Value',0)
            %set(handles.rbtn_showDiso,'Value',0)
            %set(handles.rbtn_showDf,'Value',0)
            %set(handles.rbtn_showDw,'Value',0)
            
            ax=gca;
            cla(ax)
            ax.Toolbar.Visible = 'on';


            set(handles.txt_status,'String','Loading Case...');
            [filename, pathname] = uigetfile('*.mat', 'Select case mat file');
            if isequal(filename,0)||isequal(pathname,0)
            else
                clear patient
                load([pathname filename]);
                set(handles.txt_path,'String',['Path: ', pathname filename]);
            end
            set(handles.txt_patient_id,'String',[patient.patient_id{1}]);
            set(handles.txt_patient_id,'Visible','on')
            set(handles.btn_save_case,'Enable','on');
            set(handles.btn_load_images,'Enable','off');
            
            %borro cosas que no necesito
            if (isfield(patient,'himg'))
                patient = rmfield(patient,'himg');
            end
            %if (isfield(patient,'info'))
            %    patient = rmfield(patient,'info');
            %end
            patient.info=0;
            
            
            if (isfield(patient,'stack'))
                set(handles.img,'Visible','on');
                axes(handles.img);
                patient.nimg = fix(patient.stacksize/2);
                patient.show = 0;
            
                data = get(handles.tbl_roi,'data');
                for i=1:length(patient.roi_level)
                    data(i,1) = cellstr(['<html><table border=0 width=300 bgcolor=' rgb2hex([patient.roi_level(i) patient.roi_level(i) patient.roi_level(i)]) '><TR><TD> '  '</TD></TR> </table></html>']);
                    data(i,2) = cellstr(patient.roi_list(i));
                    data(i,3) = {patient.dvh_roi(i)};
                end
            
                set(handles.tbl_roi,'data',data);
                set(handles.tbl_roi,'Visible','on');
            
                ax=gca;
                cla(ax)
                ax.Toolbar.Visible = 'on';
            
                plot_choosen_dose(hObject,1);
            
                set(handles.btn_left_arrow,'Enable','on');
                set(handles.btn_rigth_arrow,'Enable','on');
                set(handles.txt_nstack,'Enable','on');
            
                set(handles.inp_xcor,'Enable','on');
                set(handles.inp_ycor,'Enable','on');
                set(handles.inp_zcor,'Enable','on');
                set(handles.btn_go,'Enable','on');
            
                set(handles.btn_load_1fr,'Enable','on');
                set(handles.btn_load_2fr,'Enable','on');
            
                set(handles.inp_xmin,'Enable','on');
                set(handles.inp_ymin,'Enable','on');
                set(handles.inp_zmin,'Enable','on');
                set(handles.inp_xmax,'Enable','on');
                set(handles.inp_ymax,'Enable','on');
                set(handles.inp_zmax,'Enable','on');
                set(handles.inp_xstep,'Enable','on');
                set(handles.inp_ystep,'Enable','on');
                set(handles.inp_zstep,'Enable','on');
            
            
            end
                set(handles.btn_showMCNPerror,'Enable','on');
                set(handles.btn_abs_dose,'Enable','on');
                set(handles.btn_w_dose,'Enable','on');
                set(handles.btn_isoe_dose,'Enable','on');
                set(handles.btn_tir_point,'Enable','on');
                set(handles.inp_tir,'Enable','on');
                set(handles.inp_1stb10,'Enable','on')


            if (isfield(patient,'boron1'))
                 set(handles.inp_1stb10,'String',patient.boron1);
            end
            if (isfield(patient,'xmin'))
                set(handles.inp_xmin,'String',patient.xmin);
            end
            if (isfield(patient,'ymin'))
                set(handles.inp_ymin,'String',patient.ymin);
            end
            if (isfield(patient,'zmin'))
                set(handles.inp_zmin,'String',patient.zmin);
            end
            if (isfield(patient,'xmax'))
                set(handles.inp_xmax,'String',patient.xmax);
            end
            if (isfield(patient,'ymax'))
                set(handles.inp_ymax,'String',patient.ymax);
            end
            if (isfield(patient,'zmax'))
                set(handles.inp_zmax,'String',patient.zmax);
            end
            if (isfield(patient,'xstep'))
                set(handles.inp_xstep,'String',patient.xstep);
            end
            if (isfield(patient,'ystep'))
                set(handles.inp_ystep,'String',patient.ystep);
            end
            if (isfield(patient,'zstep'))
                set(handles.inp_zstep,'String',patient.zstep);
            end
            
            
            set(handles.txt_status,'String','Load Case...OK');
        end

        % Button pushed function: btn_load_images
        function btn_load_images_Callback(app, event)
            % btn_load_images: load new patient images.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            [file,dir] = uigetfile('*.tif;*.tiff','Select tiff file');
            if isequal(file,0)||isequal(dir,0)
                patient.stack=[];
            else
                fimg = fullfile(dir,file);
                patient.stacksize = size(imfinfo(fimg),1);
                GralInf=imfinfo(fimg);
                patient.stacksizeXY=GralInf.Width;
                SizeXY=patient.stacksizeXY;
                for i=1:patient.stacksize
                     patient.stack(:,:,i)=imread(fimg,'tif',i);
                end
                set(handles.img,'Visible','on');
                axes(handles.img);
                patient.nimg = fix(patient.stacksize/2);
                patient.px = size(patient.stack,1);
                patient.py = size(patient.stack,2);
                levels = histcounts(reshape(double(patient.stack),1,numel(patient.stack)),-0.5:255.5);
                levels = find(levels)-1;
            
                data = get(handles.tbl_roi,'data');
                for i=1:length(levels)
                    data(i,1) = cellstr(['<html><table border=0 width=300 bgcolor=' rgb2hex([levels(i) levels(i) levels(i)]) '><TR><TD> '  '</TD></TR> </table></html>']);
                    data(i,2) = {i};
                    data{i,3} = 0;
                end
            
                % %data=[];
                % %data = cell(length(levels),3);
                % data = get(handles.tbl_roi,'data');
                % for i = 1:length(levels)
                %     color_hex = rgb2hex([levels(i), levels(i), levels(i)]); % Convertir a color hexadecimal
                %     data{i,1} = ['<html><table border=0 width=300 bgcolor=' color_hex '><TR><TD></TD></TR></table></html>'];
                %     data{i,2} = {i};
                %     data{i,3} = 0;
                % end
            
                % patient.stack = permute(patient.stack,[2,1,3]);
                % patient.nimg = fix(patient.stacksize/2);
                % patient.px = size(patient.stack,1);
                % patient.py = size(patient.stack,2);


                patient.roi_list = string(1:length(levels));
                patient.roi_level = levels;
                patient.dvh_roi = zeros(length(levels),1);
            
                set(handles.tbl_roi,'data',data);
                set(handles.btn_left_arrow,'Enable','on');
                set(handles.btn_rigth_arrow,'Enable','on');
            
                set(handles.inp_xcor,'Enable','on');
                set(handles.inp_ycor,'Enable','on');
                set(handles.inp_zcor,'Enable','on');
                set(handles.btn_go,'Enable','on');
            
                set(handles.txt_nstack,'Enable','on');
                set(handles.btn_load_1fr,'Enable','on');
            
                set(handles.inp_xmin,'Enable','on');
                set(handles.inp_ymin,'Enable','on');
                set(handles.inp_zmin,'Enable','on');
                set(handles.inp_xmax,'Enable','on');
                set(handles.inp_ymax,'Enable','on');
                set(handles.inp_zmax,'Enable','on');
                set(handles.inp_xstep,'Enable','on');
                set(handles.inp_ystep,'Enable','on');
                set(handles.inp_zstep,'Enable','on');
                set(handles.tbl_roi,'Visible','on');


                patient.show = 0;
                plot_choosen_dose(hObject,1);
                ax=gca;
                ax.Toolbar.Visible = 'on';
            end
        end

        % Button pushed function: btn_mX
        function btn_mX_Callback(app, event)
            % --- Executes on button press in btn_mX.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_mX (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient
            
            set(handles.txt_status,'String','Mirroring X axis...')
            
            patient.D1c.boro = patient.D1c.boro(end:-1:1,:,:);
            patient.D1c.fast = patient.D1c.fast(end:-1:1,:,:);
            patient.D1c.thn = patient.D1c.thn(end:-1:1,:,:);
            patient.D1c.g = patient.D1c.g(end:-1:1,:,:);
            
            if (isfield(patient,'D2'))
                patient.D2c.boro = patient.D2c.boro(end:-1:1,:,:);
                patient.D2c.fast = patient.D2c.fast(end:-1:1,:,:);
                patient.D2c.thn = patient.D2c.thn(end:-1:1,:,:);
                patient.D2c.g = patient.D2c.g(end:-1:1,:,:);
            end
            
            patient.D1.boro = patient.D1.boro(end:-1:1,:,:);
            patient.D1.fast = patient.D1.fast(end:-1:1,:,:);
            patient.D1.thn = patient.D1.thn(end:-1:1,:,:);
            patient.D1.g = patient.D1.g(end:-1:1,:,:);
            
            
            patient.Dt.boro = patient.Dt.boro(end:-1:1,:,:);
            patient.Dt.fast = patient.Dt.fast(end:-1:1,:,:);
            patient.Dt.thn = patient.Dt.thn(end:-1:1,:,:);
            patient.Dt.g = patient.Dt.g(end:-1:1,:,:);
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            set(handles.txt_status,'String','X axis mirrored')
            set(handles.btn_mX,'BackgroundColor', [0 1 0]);
        end

        % Button pushed function: btn_mY
        function btn_mY_Callback(app, event)
            % --- Executes on button press in btn_mY.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_mY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient
            
            set(handles.txt_status,'String','Mirroring Y axis...')
            
            patient.D1c.boro = patient.D1c.boro(:,end:-1:1,:);
            patient.D1c.fast = patient.D1c.fast(:,end:-1:1,:);
            patient.D1c.thn = patient.D1c.thn(:,end:-1:1,:);
            patient.D1c.g = patient.D1c.g(:,end:-1:1,:);
            
            if (isfield(patient,'D2'))
                patient.D2c.boro = patient.D2c.boro(:,end:-1:1,:);
                patient.D2c.fast = patient.D2c.fast(:,end:-1:1,:);
                patient.D2c.thn = patient.D2c.thn(:,end:-1:1,:);
                patient.D2c.g = patient.D2c.g(:,end:-1:1,:);
            end
            
            patient.D1.boro = patient.D1.boro(:,end:-1:1,:);
            patient.D1.fast = patient.D1.fast(:,end:-1:1,:);
            patient.D1.thn = patient.D1.thn(:,end:-1:1,:);
            patient.D1.g = patient.D1.g(:,end:-1:1,:);
            
            
            patient.Dt.boro = patient.Dt.boro(:,end:-1:1,:);
            patient.Dt.fast = patient.Dt.fast(:,end:-1:1,:);
            patient.Dt.thn = patient.Dt.thn(:,end:-1:1,:);
            patient.Dt.g = patient.Dt.g(:,end:-1:1,:);
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            set(handles.txt_status,'String','Y axis mirrored')
            set(handles.btn_mY,'BackgroundColor', [0 1 0]);
        end

        % Button pushed function: btn_mZ
        function btn_mZ_Callback(app, event)
            % --- Executes on button press in btn_mZ.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_mZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient
            
            set(handles.txt_status,'String','Mirroring Z axis...')
            
            patient.D1c.boro = patient.D1c.boro(:,:,end:-1:1);
            patient.D1c.fast = patient.D1c.fast(:,:,end:-1:1);
            patient.D1c.thn = patient.D1c.thn(:,:,end:-1:1);
            patient.D1c.g = patient.D1c.g(:,:,end:-1:1);
            
            if (isfield(patient,'D2'))
                patient.D2c.boro = patient.D2c.boro(:,:,end:-1:1);
                patient.D2c.fast = patient.D2c.fast(:,:,end:-1:1);
                patient.D2c.thn = patient.D2c.thn(:,:,end:-1:1);
                patient.D2c.g = patient.D2c.g(:,:,end:-1:1);
            end
            
            patient.D1.boro = patient.D1.boro(:,end:-1:1,:);
            patient.D1.fast = patient.D1.fast(:,end:-1:1,:);
            patient.D1.thn = patient.D1.thn(:,end:-1:1,:);
            patient.D1.g = patient.D1.g(:,end:-1:1,:);
            
            patient.Dt.boro = patient.Dt.boro(:,:,end:-1:1);
            patient.Dt.fast = patient.Dt.fast(:,:,end:-1:1);
            patient.Dt.thn = patient.Dt.thn(:,:,end:-1:1);
            patient.Dt.g = patient.Dt.g(:,:,end:-1:1);
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            set(handles.txt_status,'String','Z axis mirrored')
            set(handles.btn_mZ,'BackgroundColor', [0 1 0]);
        end

        % Button pushed function: btn_new_case
        function btn_new_case_Callback(app, event)
            % btn_new_case: ininizialize new patient case.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            patient=struc([]);
            
            clc
            if ishandle(2);close(2);end
            if ishandle(3);close(3);end
            
            set(handles.inp_xmin,'Enable','off');
            set(handles.inp_ymin,'Enable','off');
            set(handles.inp_zmin,'Enable','off');
            set(handles.inp_xmax,'Enable','off');
            set(handles.inp_ymax,'Enable','off');
            set(handles.inp_zmax,'Enable','off');
            set(handles.inp_xstep,'Enable','off');
            set(handles.inp_ystep,'Enable','off');
            set(handles.inp_zstep,'Enable','off');
            set(handles.tbl_roi,'Visible','off');
            set(handles.btn_left_arrow,'Enable','off');
            set(handles.btn_rigth_arrow,'Enable','off');
            set(handles.txt_nstack,'Enable','off');
            set(handles.inp_xcor,'Enable','off');
            set(handles.inp_ycor,'Enable','off');
            set(handles.inp_zcor,'Enable','off');
            set(handles.btn_go,'Enable','off');
            set(handles.btn_load_1fr,'Enable','off');
            set(handles.btn_load_2fr,'Enable','off');
            set(handles.inp_tir,'Enable','off')
            set(handles.btn_showMCNPerror,'Enable','off');
            set(handles.btn_abs_dose,'Enable','off');
            set(handles.btn_w_dose,'Enable','off');
            set(handles.btn_isoe_dose,'Enable','off');
            %set(handles.pnl_matrixrotation,'Enable','off');
            set(handles.pnl_matrixrotation,'Enable','off');
            set(handles.btn_mX,'Enable','off');
            set(handles.btn_mY,'Enable','off');
            set(handles.btn_mZ,'Enable','off');
            set(handles.btn_sXY,'Enable','off');
            set(handles.btn_sYZ,'Enable','off');
            set(handles.btn_sZX,'Enable','off');
            set(handles.btn_create_dvh,'Enable','off');
            set(handles.btn_utcp,'Enable','off');
            set(handles.btn_tir_point,'Enable','off');
            %set(handles.btn_tir_region,'Enable','off');
            set(handles.inp_xmin,'String','');
            set(handles.inp_ymin,'String','');
            set(handles.inp_zmin,'String','');
            set(handles.inp_xmax,'String','');
            set(handles.inp_ymax,'String','');
            set(handles.inp_zmax,'String','');
            set(handles.inp_xstep,'String','');
            set(handles.inp_ystep,'String','');
            set(handles.inp_zstep,'String','');
            set(handles.inp_1stb10,'String','');
            set(handles.inp_tir,'String','1')
            set(handles.inp_xcor,'String','');
            set(handles.inp_ycor,'String','');
            set(handles.inp_zcor,'String','');
            set(handles.txt_nstack,'String','');
            %set(handles.rbtn_showMCNPerror,'Value',0)
            %set(handles.rbtn_showDiso,'Value',0)
            %set(handles.rbtn_showDf,'Value',0)
            %set(handles.rbtn_showDw,'Value',0)
            
            ax=gca;
            cla(ax)
            ax.Toolbar.Visible = 'on';
            
            patient.patient_id = inputdlg('Patient ID');
            set(handles.txt_patient_id,'String',[patient.patient_id{1}]);
            set(handles.txt_patient_id,'Visible','on')
            set(handles.btn_save_case,'Enable','on');
            set(handles.btn_load_images,'Enable','on');
            
            if isempty(patient.patient_id{1})
               txt='Unidentified patient';
               set(handles.txt_patient_id,'String',txt);
               set(handles.txt_patient_id,'ForegroundColor',[1 0 0]) %rojo
            end
            patient.info=0;
        end

        % Button pushed function: btn_rigth_arrow
        function btn_rigth_arrow_Callback(app, event)
            % --- Executes on button press in btn_rigth_arrow.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            if(patient.nimg<patient.stacksize)
                patient.nimg =patient.nimg+1;
                plot_choosen_dose(hObject,1);
            end
        end

        % Button pushed function: btn_sXY
        function btn_sXY_Callback(app, event)
            % --- Executes on button press in btn_sXY.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_sXY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient
            
            set(handles.txt_status,'String','Switching X and Y axis...')
            
            patient.D1c.boro = permute(patient.D1c.boro,[2 1 3]);
            patient.D1c.fast = permute(patient.D1c.fast,[2 1 3]);
            patient.D1c.thn = permute(patient.D1c.thn,[2 1 3]);
            patient.D1c.g = permute(patient.D1c.g,[2 1 3]);
            
            xmp = patient.xstep/2;
            ymp = patient.ystep/2;
            zmp = patient.zstep/2;
            
            x=patient.xmin+xmp:patient.xstep:patient.xmax-xmp;
            y=patient.ymin+ymp:patient.ystep:patient.ymax-ymp;
            z=patient.zmin+zmp:patient.zstep:patient.zmax-zmp;
            
            [X,Y,Z] = ndgrid(x,y,z);
            [Xc,Yc,Zc]=ndgrid(patient.D1c.xc,patient.D1c.yc,patient.D1c.zc);
            
            % Interpolo componente a componente.
            set(handles.txt_status,'String','Interpolating 1st BNCT...')
            
            patient.D1.boro = interpn(Xc,Yc,Zc,patient.D1c.boro,X,Y,Z,'linear');
            patient.D1.fast = interpn(Xc,Yc,Zc,patient.D1c.fast,X,Y,Z,'linear');
            patient.D1.thn = interpn(Xc,Yc,Zc,patient.D1c.thn,X,Y,Z,'linear');
            patient.D1.g = interpn(Xc,Yc,Zc,patient.D1c.g,X,Y,Z,'linear');
            
            if(isfield(patient,'D2'))
                patient.D2c.boro = permute(patient.D2c.boro,[2 1 3]);
                patient.D2c.fast = permute(patient.D2c.fast,[2 1 3]);
                patient.D2c.thn = permute(patient.D2c.thn,[2 1 3]);
                patient.D2c.g = permute(patient.D2c.g,[2 1 3]);
            
                set(handles.txt_status,'String','Interpolating 2nd BNCT...')
            
                patient.D2.boro = interpn(Xc,Yc,Zc,patient.D2c.boro,X,Y,Z,'linear');
                patient.D2.fast = interpn(Xc,Yc,Zc,patient.D2c.fast,X,Y,Z,'linear');
                patient.D2.thn = interpn(Xc,Yc,Zc,patient.D2c.thn,X,Y,Z,'linear');
                patient.D2.g = interpn(Xc,Yc,Zc,patient.D2c.g,X,Y,Z,'linear');
            
                patient.Dt.boro = (patient.f1*patient.D1.boro*patient.boron1)+(patient.f2*patient.D2.boro*patient.boron2);
                patient.Dt.fast = (patient.f1*patient.D1.fast)+(patient.f2*patient.D2.fast);
                patient.Dt.thn = (patient.f1*patient.D1.thn)+(patient.f2*patient.D2.thn);
                patient.Dt.g = (patient.f1*patient.D1.g)+(patient.f2*patient.D2.g);
            else
                patient.Dt.boro = patient.D1.boro*patient.boron1;
                patient.Dt.fast = patient.D1.fast;
                patient.Dt.thn = patient.D1.thn;
                patient.Dt.g = patient.D1.g;
            end
            patient.tir = 1;
            set(handles.inp_tir,'Enable','on')
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            set(handles.txt_status,'String','X and Y axis switched...')
        end

        % Button pushed function: btn_sYZ
        function btn_sYZ_Callback(app, event)
            % --- Executes on button press in btn_sYZ.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_sYZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient
            
            set(handles.txt_status,'String','Switching Y and Z axis...')
            
            patient.D1c.boro = permute(patient.D1c.boro,[1 3 2]);
            patient.D1c.fast = permute(patient.D1c.fast,[1 3 2]);
            patient.D1c.thn = permute(patient.D1c.thn,[1 3 2]);
            patient.D1c.g = permute(patient.D1c.g,[1 3 2]);
            
            xmp = patient.xstep/2;
            ymp = patient.ystep/2;
            zmp = patient.zstep/2;
            
            x=patient.xmin+xmp:patient.xstep:patient.xmax-xmp;
            y=patient.ymin+ymp:patient.ystep:patient.ymax-ymp;
            z=patient.zmin+zmp:patient.zstep:patient.zmax-zmp;
            
            [X,Y,Z] = ndgrid(x,y,z);
            [Xc,Yc,Zc]=ndgrid(patient.D1c.xc,patient.D1c.yc,patient.D1c.zc);
            
            % Interpolo componente a componente.
            set(handles.txt_status,'String','Interpolating 1st BNCT...')
            
            patient.D1.boro = interpn(Xc,Yc,Zc,patient.D1c.boro,X,Y,Z,'linear');
            patient.D1.fast = interpn(Xc,Yc,Zc,patient.D1c.fast,X,Y,Z,'linear');
            patient.D1.thn = interpn(Xc,Yc,Zc,patient.D1c.thn,X,Y,Z,'linear');
            patient.D1.g = interpn(Xc,Yc,Zc,patient.D1c.g,X,Y,Z,'linear');
            
            if(isfield(patient,'D2'))
                patient.D2c.boro = permute(patient.D2c.boro,[1 3 2]);
                patient.D2c.fast = permute(patient.D2c.fast,[1 3 2]);
                patient.D2c.thn = permute(patient.D2c.thn,[1 3 2]);
                patient.D2c.g = permute(patient.D2c.g,[1 3 2]);
            
                set(handles.txt_status,'String','Interpolating 2nd BNCT...')
            
                patient.D2.boro = interpn(Xc,Yc,Zc,patient.D2c.boro,X,Y,Z,'linear');
                patient.D2.fast = interpn(Xc,Yc,Zc,patient.D2c.fast,X,Y,Z,'linear');
                patient.D2.thn = interpn(Xc,Yc,Zc,patient.D2c.thn,X,Y,Z,'linear');
                patient.D2.g = interpn(Xc,Yc,Zc,patient.D2c.g,X,Y,Z,'linear');
            
                patient.Dt.boro = (patient.f1*patient.D1.boro*patient.boron1)+(patient.f2*patient.D2.boro*patient.boron2);
                patient.Dt.fast = (patient.f1*patient.D1.fast)+(patient.f2*patient.D2.fast);
                patient.Dt.thn = (patient.f1*patient.D1.thn)+(patient.f2*patient.D2.thn);
                patient.Dt.g = (patient.f1*patient.D1.g)+(patient.f2*patient.D2.g);
            else
                patient.Dt.boro = patient.D1.boro*patient.boron1;
                patient.Dt.fast = patient.D1.fast;
                patient.Dt.thn = patient.D1.thn;
                patient.Dt.g = patient.D1.g;
            end
            patient.tir = 1;
            set(handles.inp_tir,'Enable','on')
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            set(handles.txt_status,'String','Y and Z axis switched...')
        end

        % Button pushed function: btn_sZX
        function btn_sZX_Callback(app, event)
            % --- Executes on button press in btn_sZX.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_sZX (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient
            
            set(handles.txt_status,'String','Switching X and Z axis...')
            
            patient.D1c.boro = permute(patient.D1c.boro,[3 2 1]);
            patient.D1c.fast = permute(patient.D1c.fast,[3 2 1]);
            patient.D1c.thn = permute(patient.D1c.thn,[3 2 1]);
            patient.D1c.g = permute(patient.D1c.g,[3 2 1]);
            
            xmp = patient.xstep/2;
            ymp = patient.ystep/2;
            zmp = patient.zstep/2;
            
            x=patient.xmin+xmp:patient.xstep:patient.xmax-xmp;
            y=patient.ymin+ymp:patient.ystep:patient.ymax-ymp;
            z=patient.zmin+zmp:patient.zstep:patient.zmax-zmp;
            
            [X,Y,Z] = ndgrid(x,y,z);
            [Xc,Yc,Zc]=ndgrid(patient.D1c.xc,patient.D1c.yc,patient.D1c.zc);
            
            % Interpolo componente a componente.
            set(handles.txt_status,'String','Interpolating 1st BNCT...')
            
            patient.D1.boro = interpn(Xc,Yc,Zc,patient.D1c.boro,X,Y,Z,'linear');
            patient.D1.fast = interpn(Xc,Yc,Zc,patient.D1c.fast,X,Y,Z,'linear');
            patient.D1.thn = interpn(Xc,Yc,Zc,patient.D1c.thn,X,Y,Z,'linear');
            patient.D1.g = interpn(Xc,Yc,Zc,patient.D1c.g,X,Y,Z,'linear');
            
            if(isfield(patient,'D2'))
                patient.D2c.boro = permute(patient.D2c.boro,[3 2 1]);
                patient.D2c.fast = permute(patient.D2c.fast,[3 2 1]);
                patient.D2c.thn = permute(patient.D2c.thn,[3 2 1]);
                patient.D2c.g = permute(patient.D2c.g,[3 2 1]);
            
                set(handles.txt_status,'String','Interpolating 2nd BNCT...')
            
                patient.D2.boro = interpn(Xc,Yc,Zc,patient.D2c.boro,X,Y,Z,'linear');
                patient.D2.fast = interpn(Xc,Yc,Zc,patient.D2c.fast,X,Y,Z,'linear');
                patient.D2.thn = interpn(Xc,Yc,Zc,patient.D2c.thn,X,Y,Z,'linear');
                patient.D2.g = interpn(Xc,Yc,Zc,patient.D2c.g,X,Y,Z,'linear');
            
                patient.Dt.boro = (patient.f1*patient.D1.boro*patient.boron1)+(patient.f2*patient.D2.boro*patient.boron2);
                patient.Dt.fast = (patient.f1*patient.D1.fast)+(patient.f2*patient.D2.fast);
                patient.Dt.thn = (patient.f1*patient.D1.thn)+(patient.f2*patient.D2.thn);
                patient.Dt.g = (patient.f1*patient.D1.g)+(patient.f2*patient.D2.g);
            else
                patient.Dt.boro = patient.D1.boro*patient.boron1;
                patient.Dt.fast = patient.D1.fast;
                patient.Dt.thn = patient.D1.thn;
                patient.Dt.g = patient.D1.g;
            end
            patient.tir = 1;
            set(handles.inp_tir,'Enable','on')
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            set(handles.txt_status,'String','X and Z axis switched...')
        end

        % Button pushed function: btn_save_case
        function btn_save_case_Callback(app, event)
            % btn_save_case: save current patient case.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global patient;
            
            [filename, pathname] = uiputfile('*.mat', 'Save case as...');
            save([pathname filename],'patient');
            %txt=['Path: ',pathname,filename];
            %set(handles.txt_path,'String',txt);
            msgbox(' Save OK', 'Confirmation', 'help');
        end

        % Button pushed function: btn_showMCNPerror
        function btn_showMCNPerror_Callback(app, event)
            % --- Executes on button press in btn_showMCNPerror.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_showMCNPerror (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % --- Executes on button press in btn_abs_dose.
            global patient;
            
            set(handles.txt_status,'String','Showing MCNP Boron Dose RelError...')
            set(handles.rbtn_showMCNPerror,'Enable','off')
            set(handles.rbtn_showMCNPerror,'Value',1)
            
            % prompt = {'Error'};
            % dlg_title = 'Error';
            % num_lines = 1;
            % defaultans = {'1'};
            % answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            % nE = str2double(answ{1});
            
             list={'Boro','Thermal','Fast','Gamma'};
             [nE,tf] = listdlg('PromptString','Error','ListString',list,'SelectionMode','single');
            
            % patient.Dt.E(patient.stack == 0) = NaN;
            [patient.xdmax,patient.ydmax,patient.zdmax] = ind2sub(size(patient.Dt.E(:,:,:,nE)),find(patient.Dt.E(:,:,:,nE)==min(min(min(patient.Dt.E(:,:,:,nE))))));
            
            
            patient.px = patient.xdmax;
            patient.py = patient.ydmax;
            patient.nimg = patient.zdmax;
            patient.nE=nE;
            
            patient.show = 4;
            
            if(isfield(patient,'himg'))
              patient = rmfield(patient,'himg');
            end
            if(isfield(patient,'dose_surf'))
              patient = rmfield(patient,'dose_surf');
            end
            if(isfield(patient,'hisoc'))
              patient = rmfield(patient,'hisoc');
            end
            if(isfield(patient,'hplotmax'))
              patient = rmfield(patient,'hplotmax');
            end
            
            ax=gca;
            cla(ax)
            ax.Toolbar.Visible = 'on';
            
            plot_choosen_dose(hObject,1)
            
            if(isfield(patient,'Dw'))
                set(handles.rbtn_showDw,'Enable','off')
                set(handles.rbtn_showDw,'Value',0)
            end
            
            if(isfield(patient,'Diso'))
                set(handles.rbtn_showDiso,'Enable','off')
                set(handles.rbtn_showDiso,'Value',0)
            end
            
            if(isfield(patient,'Df'))
                set(handles.rbtn_showDf,'Enable','off')
                set(handles.rbtn_showDf,'Value',0)
            end
            
            set(handles.txt_status,'String','Showing MCNP Boron Dose RelError...')
            set(handles.btn_create_dvh,'Enable','off')
            set(handles.btn_utcp,'Enable','off')
            set(handles.btn_tir_point,'Enable','off')
            set(handles.btn_find,'Enable','off')
        end

        % Button pushed function: btn_tir_point
        function btn_tir_point_Callback(app, event)
            % --- Executes on button press in btn_tir_point.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_tir_point (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            
            switch patient.show
                case 1
                    % Calculo las proporciones de las componentes respecto al punto elegido.
                    Dp = patient.Df(patient.px,patient.py,patient.nimg);
                    rb = patient.rb(patient.px,patient.py,patient.nimg);
                    rf = patient.rf(patient.px,patient.py,patient.nimg);
                    rt = patient.rt(patient.px,patient.py,patient.nimg);
                    rg = patient.rg(patient.px,patient.py,patient.nimg);
            
                    parametros_ntcp = model_selector(3);
                    % Construct a questdlg with three options
                    choice = questdlg('Dose prescription to...','Choose...','NTCP','Maximum','NTCP');
                    % Handle response
                    switch choice
                        case 'NTCP'
                            prompt = {'Enter NCTP [%] limit:'};
                            dlg_title = 'NTCP limit';
                            num_lines = 1;
                            defaultans = {'5'};
                            answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
                            lim = str2double(answ)/100;
                            patient.tir = fzero(@(t) ntcp2(Dp*t,rb,rf+rt,rg,parametros_ntcp)-lim,60);
                            set(handles.inp_tir,'String',num2str(patient.tir,'%0.2f'));
                        case 'Maximum'
                            prompt = {'Enter Maximum Absorbed Dose [Gy]:'};
                            dlg_title = 'Maximum Absorbed Dose [Gy]:';
                            num_lines = 1;
                            defaultans = {'6'};
                            answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
                            lim = str2double(answ);
                            patient.tir = fzero(@(t) lim-(Dp*t),60);
                            set(handles.inp_tir,'String',num2str(patient.tir,'%0.2f'));
                    end
            end
        end

        % Button pushed function: btn_utcp
        function btn_utcp_Callback(app, event)
            % --- Executes on button press in btn_utcp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_utcp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            
            prompt = {'Total time [min]:','step [min]:'};
            dlg_title = 'UTCP';
            num_lines = 1;
            defaultans = {'400','4'};
            answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
            rois = patient.roi_level(patient.dvh_roi == 1);
            mask = nan(size(patient.stack));
            
            for i=1:length(rois)
                mask(patient.stack==rois(i))=1;
            end
            
            tiempo =0:str2num(answ{2}):str2num(answ{1});
            tcp_t = zeros(1,length(tiempo));
            ntcp_t = zeros(1,length(tiempo));
            
            h=waitbar(0,'Calculando UTCP...');
            
            % param = [2.468388470867692 0.000100508105922 3.090888558919538 0.000091579941911];
            % TD50g = 39.8;
            % mg = 0.17;
            
            xm = patient.px;
            ym = patient.py;
            zm = patient.nimg;
            dv = patient.xstep * patient.ystep * patient.zstep;
            
            parametros_iso = model_selector(1);
            
            prompt = {'Tumor/blood B10 ratio:'};
            dlgtitle = 'Tumor/blood B10 ratio';
            dims = [1 35];
            definput = {'3.5'};
            bratio = inputdlg(prompt,dlgtitle,dims,definput);
            
            parametros_tcp = model_selector(2);
            parametros_ntcp = model_selector(3);
            
            for i=1:length(tiempo)
            
                Dmask2 = isodose(patient.Dt,tiempo(i),bratio,parametros_iso) .* mask;
                dvh_dose2 = Dmask2(mask==1);
                tcp_t(i) = TCPc(dvh_dose2,dv,parametros_tcp);
                %[~,ntcp_t(i)] = ntcp(patient.Df(xm,ym,zm)*(tiempo(i)*patient.f1),patient.rb(xm,ym,zm),patient.rf(xm,ym,zm)+patient.rt(xm,ym,zm),patient.rg(xm,ym,zm),parametros_ntcp);
                ntcp_t(i) = ntcp2(patient.Df(xm,ym,zm)*(tiempo(i)*patient.f1),patient.rb(xm,ym,zm),patient.rf(xm,ym,zm)+patient.rt(xm,ym,zm),patient.rg(xm,ym,zm),parametros_ntcp);
                waitbar(i/length(tiempo),h);
            end
            
            delete(h);
            utcp_t=tcp_t.*(1-ntcp_t);
            tmax = ind2sub(size(utcp_t),find(utcp_t==max(utcp_t)));
            ob(1) = tiempo(tmax);
            ob(2) = utcp_t(tmax);
            ob(3) = tcp_t(tmax);
            ob(4) = ntcp_t(tmax);
            
            figure
            plot(tiempo,tcp_t,'k')
            hold on
            plot(tiempo,ntcp_t,'k--')
            plot(tiempo,utcp_t,'k','LineWidth',1.5)
            plot([ob(1) ob(1)],[0 1],'k:')
            plot(ob(1),ob(2),'k.')
            plot(ob(1),ob(3),'k.')
            plot(ob(1),ob(4),'k.')
            
            legend('\it{TCP }','\it{NTCP }','\it{UTCP }');
            xlabel('Treatment time [min]');
            ylabel('Probability');
            box on
            grid on
        end

        % Button pushed function: btn_w_dose
        function btn_w_dose_Callback(app, event)
            % --- Executes on button press in btn_w_dose.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_w_dose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            set(handles.txt_status,'String','Calculating RBEw Dose...')
            
            patient.Dt.boro = patient.D1.boro*patient.boron1;
            
            
            prompt = {'Boron:','Thermal Neutron:','Fast Neutron:','Gamma:','Tissue/Blood Ratio:'};
            dlg_title = 'RBE factors';
            num_lines = 1;
            defaultans = {'3.8','3.2','3.2','1','3.5'};
            % Hanna
            %defaultans = {'3.8','3.2','3.2','1','1'}; % la informacion del ratio esta en cada voxel
            answ = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
            %RBE Factors.
            rbe.boro = str2double(answ{1});
            rbe.thn = str2double(answ{2});
            rbe.fastn = str2double(answ{3});
            rbe.g = str2double(answ{4});
            %RBEw Dose.
            patient.Dw = ((str2double(answ{5})*patient.Dt.boro*rbe.boro) + patient.Dt.fast*rbe.fastn + patient.Dt.thn*rbe.thn + patient.Dt.g*rbe.g)*patient.tir;
            % Mascara
            Dose=patient.Dw;
            Dose(patient.stack==0)=0;
            patient.Dw=Dose;
            % patient.Dw(patient.stack == 0)= NaN;
            [patient.xdmax,patient.ydmax,patient.zdmax] = ind2sub(size(patient.Dw),find(patient.Dw==max(max(max(patient.Dw)))));
            patient.Dwmax = max(max(max(patient.Dw)));
            patient.nimg = patient.zdmax;
            patient.px = patient.xdmax;
            patient.py = patient.ydmax;
            
            set(handles.rbtn_showDw,'Enable','off')
            set(handles.rbtn_showDw,'Value',1)
            
            patient.show = 2;
            if(isfield(patient,'himg'))
              patient = rmfield(patient,'himg');
            end
            if(isfield(patient,'dose_surf'))
              patient = rmfield(patient,'dose_surf');
            end
            if(isfield(patient,'hisoc'))
              patient = rmfield(patient,'hisoc');
            end
            if(isfield(patient,'hplotmax'))
              patient = rmfield(patient,'hplotmax');
            end
            
            ax=gca;
            cla(ax)
            ax.Toolbar.Visible = 'on';
            
            plot_choosen_dose(hObject,1)
            
            if(isfield(patient,'Dt'))
                set(handles.rbtn_showMCNPerror,'Enable','off')
                set(handles.rbtn_showMCNPerror,'Value',0)
            end
            
            if(isfield(patient,'Df'))
                set(handles.rbtn_showDf,'Enable','off')
                set(handles.rbtn_showDf,'Value',0)
            end
            
            if(isfield(patient,'Diso'))
                set(handles.rbtn_showDiso,'Enable','off')
                set(handles.rbtn_showDiso,'Value',0)
            end
            set(handles.txt_status,'String','Calculating RBEw Dose...OK')
            set(handles.btn_create_dvh,'Enable','on')
            % set(handles.btn_utcp,'Enable','on')
            set(handles.btn_tir_point,'Enable','on')
            set(handles.btn_find,'Enable','on')
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global patient
            
            if(exist('patient','var'))
                clear patient
            end
        end

        % Value changed function: inp_1stb10
        function inp_1stb10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to inp_1stb10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of inp_1stb10 as text
            %        str2double(get(hObject,'String')) returns contents of inp_1stb10 as a double
            global patient
            
            set(handles.txt_status,'String','Updating boron content...')
            patient.boron1 = str2double(get(hObject,'String'));
            
            if (~isfield(patient,'D2'))
                patient.Dt.boro = patient.D1.boro*patient.boron1;
                patient.Dt.fast = patient.D1.fast;
                patient.Dt.thn = patient.D1.thn;
                patient.Dt.g = patient.D1.g;
            else
                patient.Dt.boro = (patient.f1*patient.D1.boro*patient.boron1)+(patient.f2*patient.D2.boro*patient.boron2);
                patient.Dt.fast = (patient.f1*patient.D1.fast)+(patient.f2*patient.D2.fast);
                patient.Dt.thn = (patient.f1*patient.D1.thn)+(patient.f2*patient.D2.thn);
                patient.Dt.g = (patient.f1*patient.D1.g)+(patient.f2*patient.D2.g);
            end
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            
            set(handles.txt_status,'String','Boron content updated...')
        end

        % Value changed function: inp_2ndb10
        function inp_2ndb10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to inp_2ndb10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of inp_2ndb10 as text
            %        str2double(get(hObject,'String')) returns contents of inp_2ndb10 as a double
            global patient
            
            set(handles.txt_status,'String','Updating boron content...')
            patient.boron2 = str2double(get(hObject,'String'));
            
            patient.Dt.boro = (patient.f1*patient.D1.boro*patient.boron1)+(patient.f2*patient.D2.boro*patient.boron2);
            patient.Dt.fast = (patient.f1*patient.D1.fast)+(patient.f2*patient.D2.fast);
            patient.Dt.thn = (patient.f1*patient.D1.thn)+(patient.f2*patient.D2.thn);
            patient.Dt.g = (patient.f1*patient.D1.g)+(patient.f2*patient.D2.g);
            
            patient.show = 0;
            plot_choosen_dose(hObject,1);
            
            set(handles.txt_status,'String','Boron content updated...')
        end

        % Value changed function: inp_tir
        function inp_tir_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to inp_tir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of inp_tir as text
            %        str2double(get(hObject,'String')) returns contents of inp_tir as a double
            global patient;
            patient.tir=str2double(get(hObject,'String'));
        end

        % Value changed function: inp_xmax
        function inp_xmax_Callback(app, event)
            % inp_xmax: xmax input.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.xmax = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_xmin
        function inp_xmin_Callback(app, event)
            % inp_xmin: xmin input.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.xmin = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_xstep
        function inp_xstep_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.xstep = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_ymax
        function inp_ymax_Callback(app, event)
            % inp_ymax: ymax input.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.ymax = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_ymin
        function inp_ymin_Callback(app, event)
            % inp_ymin: ymin input.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.ymin = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_ystep
        function inp_ystep_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.ystep = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_zmax
        function inp_zmax_Callback(app, event)
            % inp_zmax: zmax input.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.zmax = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_zmin
        function inp_zmin_Callback(app, event)
            % inp_zmin: zmin input.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.zmin = str2double(get(hObject,'String'));
        end

        % Value changed function: inp_zstep
        function inp_zstep_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global patient;
            
            patient.zstep = str2double(get(hObject,'String'));
        end

        % Cell edit callback: tbl_roi
        function tbl_roi_CellEditCallback(app, event)
            % --- Executes when entered data in editable cell(s) in tbl_roi.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tbl_roi (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            global patient;
            
            if(eventdata.Indices(2) == 2)
                patient.roi_list (eventdata.Indices(1)) = eventdata.EditData;
            end
            
            if(eventdata.Indices(2) == 3)
                patient.dvh_roi (eventdata.Indices(1)) = eventdata.EditData;
            end
            
            data = get(handles.tbl_roi,'data');
            data(eventdata.Indices(1),eventdata.Indices(2)) = {eventdata.EditData};
            set(handles.tbl_roi,'data',data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'BnctAr: Dosimetry Tools';
            app.figure1.Tag = 'figure1';
            app.pnl_images = uipanel(app.figure1);
            app.pnl_images.Tag = 'pnl_images';
            app.img = uiaxes(app.pnl_images);
            app.img.Tag = 'img';
            app.img.Visible = 'off';
            app.pnl_case = uipanel(app.figure1);
            app.pnl_case.Tag = 'pnl_case';
            app.pnl_2ndfr = uipanel(app.pnl_case);
            app.pnl_2ndfr.Title = '2nd BNCT';
            app.pnl_2ndfr.Tag = 'pnl_2ndfr';
            app.btn_load_2fr = uibutton(app.pnl_2ndfr, 'push');
            app.btn_load_2fr.ButtonPushedFcn = createCallbackFcn(app, @btn_load_2fr_Callback, true);
            app.btn_load_2fr.Tag = 'btn_load_2fr';
            app.btn_load_2fr.Enable = 'off';
            app.btn_load_2fr.Text = 'Load Dose Matrix';
            app.txt_2b10 = uilabel(app.pnl_2ndfr);
            app.txt_2b10.Tag = 'txt_2b10';
            app.txt_2b10.Text = 'B10 [ppm]:';
            app.inp_2ndb10 = uieditfield(app.pnl_2ndfr, 'text');
            app.inp_2ndb10.ValueChangedFcn = createCallbackFcn(app, @inp_2ndb10_Callback, true);
            app.inp_2ndb10.Tag = 'inp_2ndb10';
            app.inp_2ndb10.Enable = 'off';
            app.btn_load_case = uibutton(app.pnl_case, 'push');
            app.btn_load_case.ButtonPushedFcn = createCallbackFcn(app, @btn_load_case_Callback, true);
            app.btn_load_case.Tag = 'btn_load_case';
            app.btn_load_case.Text = 'Load Case';
            app.txt_patient_id = uilabel(app.pnl_case);
            app.txt_patient_id.Tag = 'txt_patient_id';
            app.txt_patient_id.Visible = 'off';
            app.txt_patient_id.Text = 'PATIENT_ID';
            app.btn_new_case = uibutton(app.pnl_case, 'push');
            app.btn_new_case.ButtonPushedFcn = createCallbackFcn(app, @btn_new_case_Callback, true);
            app.btn_new_case.Tag = 'btn_new_case';
            app.btn_new_case.Text = 'New Case';
            app.pnl_ctscan = uipanel(app.pnl_case);
            app.pnl_ctscan.Title = 'CT Scan:';
            app.pnl_ctscan.Tag = 'pnl_ctscan';
            app.btn_load_images = uibutton(app.pnl_ctscan, 'push');
            app.btn_load_images.ButtonPushedFcn = createCallbackFcn(app, @btn_load_images_Callback, true);
            app.btn_load_images.Tag = 'btn_load_images';
            app.btn_load_images.Enable = 'off';
            app.btn_load_images.Text = 'Load Images';
            app.inp_xmin = uieditfield(app.pnl_ctscan, 'text');
            app.inp_xmin.ValueChangedFcn = createCallbackFcn(app, @inp_xmin_Callback, true);
            app.inp_xmin.Tag = 'inp_xmin';
            app.inp_xmin.Enable = 'off';
            app.inp_ymin = uieditfield(app.pnl_ctscan, 'text');
            app.inp_ymin.ValueChangedFcn = createCallbackFcn(app, @inp_ymin_Callback, true);
            app.inp_ymin.Tag = 'inp_ymin';
            app.inp_ymin.Enable = 'off';
            app.inp_zmin = uieditfield(app.pnl_ctscan, 'text');
            app.inp_zmin.ValueChangedFcn = createCallbackFcn(app, @inp_zmin_Callback, true);
            app.inp_zmin.Tag = 'inp_zmin';
            app.inp_zmin.Enable = 'off';
            app.inp_xmax = uieditfield(app.pnl_ctscan, 'text');
            app.inp_xmax.ValueChangedFcn = createCallbackFcn(app, @inp_xmax_Callback, true);
            app.inp_xmax.Tag = 'inp_xmax';
            app.inp_xmax.Enable = 'off';
            app.inp_ymax = uieditfield(app.pnl_ctscan, 'text');
            app.inp_ymax.ValueChangedFcn = createCallbackFcn(app, @inp_ymax_Callback, true);
            app.inp_ymax.Tag = 'inp_ymax';
            app.inp_ymax.Enable = 'off';
            app.inp_zmax = uieditfield(app.pnl_ctscan, 'text');
            app.inp_zmax.ValueChangedFcn = createCallbackFcn(app, @inp_zmax_Callback, true);
            app.inp_zmax.Tag = 'inp_zmax';
            app.inp_zmax.Enable = 'off';
            app.inp_zstep = uieditfield(app.pnl_ctscan, 'text');
            app.inp_zstep.ValueChangedFcn = createCallbackFcn(app, @inp_zstep_Callback, true);
            app.inp_zstep.Tag = 'inp_zstep';
            app.inp_zstep.Enable = 'off';
            app.inp_ystep = uieditfield(app.pnl_ctscan, 'text');
            app.inp_ystep.ValueChangedFcn = createCallbackFcn(app, @inp_ystep_Callback, true);
            app.inp_ystep.Tag = 'inp_ystep';
            app.inp_ystep.Enable = 'off';
            app.inp_xstep = uieditfield(app.pnl_ctscan, 'text');
            app.inp_xstep.ValueChangedFcn = createCallbackFcn(app, @inp_xstep_Callback, true);
            app.inp_xstep.Tag = 'inp_xstep';
            app.inp_xstep.Enable = 'off';
            app.txt_min = uilabel(app.pnl_ctscan);
            app.txt_min.Tag = 'txt_min';
            app.txt_min.Text = 'min';
            app.txt_max = uilabel(app.pnl_ctscan);
            app.txt_max.Tag = 'txt_max';
            app.txt_max.Text = 'max';
            app.txt_step = uilabel(app.pnl_ctscan);
            app.txt_step.Tag = 'txt_step';
            app.txt_step.Text = 'step';
            app.txt_X = uilabel(app.pnl_ctscan);
            app.txt_X.Tag = 'txt_X';
            app.txt_X.Text = 'X';
            app.txt_Z = uilabel(app.pnl_ctscan);
            app.txt_Z.Tag = 'txt_Z';
            app.txt_Z.Text = 'Z';
            app.txt_Y = uilabel(app.pnl_ctscan);
            app.txt_Y.Tag = 'txt_Y';
            app.txt_Y.Text = 'Y';
            app.pnl_1stfr = uipanel(app.pnl_case);
            app.pnl_1stfr.Title = '1st BNCT';
            app.pnl_1stfr.Tag = 'pnl_1stfr';
            app.btn_load_1fr = uibutton(app.pnl_1stfr, 'push');
            app.btn_load_1fr.ButtonPushedFcn = createCallbackFcn(app, @btn_load_1fr_Callback, true);
            app.btn_load_1fr.Tag = 'btn_load_1fr';
            app.btn_load_1fr.Enable = 'off';
            app.btn_load_1fr.Text = 'Load Dose Matrix';
            app.txt_1b10 = uilabel(app.pnl_1stfr);
            app.txt_1b10.Tag = 'txt_1b10';
            app.txt_1b10.Text = 'B10 [ppm]:';
            app.inp_1stb10 = uieditfield(app.pnl_1stfr, 'text');
            app.inp_1stb10.ValueChangedFcn = createCallbackFcn(app, @inp_1stb10_Callback, true);
            app.inp_1stb10.Tag = 'inp_1stb10';
            app.inp_1stb10.Enable = 'off';
            app.pnl_matrixrotation = uipanel(app.pnl_case);
            app.pnl_matrixrotation.Title = 'Dose Matrix Rotation';
            app.pnl_matrixrotation.Tag = 'pnl_matrixrotation';
            app.btn_mX = uibutton(app.pnl_matrixrotation, 'push');
            app.btn_mX.ButtonPushedFcn = createCallbackFcn(app, @btn_mX_Callback, true);
            app.btn_mX.Tag = 'btn_mX';
            app.btn_mX.Enable = 'off';
            app.btn_mX.Text = 'X';
            app.btn_mZ = uibutton(app.pnl_matrixrotation, 'push');
            app.btn_mZ.ButtonPushedFcn = createCallbackFcn(app, @btn_mZ_Callback, true);
            app.btn_mZ.Tag = 'btn_mZ';
            app.btn_mZ.Enable = 'off';
            app.btn_mZ.Text = 'Z';
            app.btn_mY = uibutton(app.pnl_matrixrotation, 'push');
            app.btn_mY.ButtonPushedFcn = createCallbackFcn(app, @btn_mY_Callback, true);
            app.btn_mY.Tag = 'btn_mY';
            app.btn_mY.Enable = 'off';
            app.btn_mY.Text = 'Y';
            app.btn_sXY = uibutton(app.pnl_matrixrotation, 'push');
            app.btn_sXY.ButtonPushedFcn = createCallbackFcn(app, @btn_sXY_Callback, true);
            app.btn_sXY.Tag = 'btn_sXY';
            app.btn_sXY.Enable = 'off';
            app.btn_sXY.Text = 'X <+> Y';
            app.btn_sZX = uibutton(app.pnl_matrixrotation, 'push');
            app.btn_sZX.ButtonPushedFcn = createCallbackFcn(app, @btn_sZX_Callback, true);
            app.btn_sZX.Tag = 'btn_sZX';
            app.btn_sZX.Enable = 'off';
            app.btn_sZX.Text = 'Z <+> X';
            app.btn_sYZ = uibutton(app.pnl_matrixrotation, 'push');
            app.btn_sYZ.ButtonPushedFcn = createCallbackFcn(app, @btn_sYZ_Callback, true);
            app.btn_sYZ.Tag = 'btn_sYZ';
            app.btn_sYZ.Enable = 'off';
            app.btn_sYZ.Text = 'Y <+> Z';
            app.txt_switch = uilabel(app.pnl_matrixrotation);
            app.txt_switch.Tag = 'txt_switch';
            app.txt_switch.Text = 'Switch:';
            app.txt_mirror = uilabel(app.pnl_matrixrotation);
            app.txt_mirror.Tag = 'txt_mirror';
            app.txt_mirror.Text = 'Mirror:';
            app.btn_save_case = uibutton(app.pnl_case, 'push');
            app.btn_save_case.ButtonPushedFcn = createCallbackFcn(app, @btn_save_case_Callback, true);
            app.btn_save_case.Tag = 'btn_save_case';
            app.btn_save_case.Enable = 'off';
            app.btn_save_case.Text = 'Save Case';
            app.pnl_dosimetry = uipanel(app.figure1);
            app.pnl_dosimetry.Tag = 'pnl_dosimetry';
            app.pnl_stack_explorer = uipanel(app.pnl_dosimetry);
            app.pnl_stack_explorer.Title = 'Stack Explorer';
            app.pnl_stack_explorer.Tag = 'pnl_stack_explorer';
            app.btn_left_arrow = uibutton(app.pnl_stack_explorer, 'push');
            app.btn_left_arrow.ButtonPushedFcn = createCallbackFcn(app, @btn_left_arrow_Callback, true);
            app.btn_left_arrow.Tag = 'btn_left_arrow';
            app.btn_left_arrow.Enable = 'off';
            app.btn_left_arrow.Text = '<';
            app.btn_rigth_arrow = uibutton(app.pnl_stack_explorer, 'push');
            app.btn_rigth_arrow.ButtonPushedFcn = createCallbackFcn(app, @btn_rigth_arrow_Callback, true);
            app.btn_rigth_arrow.Tag = 'btn_rigth_arrow';
            app.btn_rigth_arrow.Enable = 'off';
            app.btn_rigth_arrow.Text = '>';
            app.txt_nstack = uilabel(app.pnl_stack_explorer);
            app.txt_nstack.Tag = 'txt_nstack';
            app.txt_nstack.Enable = 'off';
            app.txt_nstack.Text = '000/000';
            app.inp_xcor = uieditfield(app.pnl_stack_explorer, 'text');
            app.inp_xcor.Tag = 'inp_xcor';
            app.inp_xcor.Enable = 'off';
            app.inp_ycor = uieditfield(app.pnl_stack_explorer, 'text');
            app.inp_ycor.Tag = 'inp_ycor';
            app.inp_ycor.Enable = 'off';
            app.inp_zcor = uieditfield(app.pnl_stack_explorer, 'text');
            app.inp_zcor.Tag = 'inp_zcor';
            app.inp_zcor.Enable = 'off';
            app.btn_go = uibutton(app.pnl_stack_explorer, 'push');
            app.btn_go.ButtonPushedFcn = createCallbackFcn(app, @btn_go_Callback, true);
            app.btn_go.Tag = 'btn_go';
            app.btn_go.Enable = 'off';
            app.btn_go.Text = 'GO';
            app.txt_expX = uilabel(app.pnl_stack_explorer);
            app.txt_expX.Tag = 'txt_expX';
            app.txt_expX.Text = 'X:';
            app.txt_expY = uilabel(app.pnl_stack_explorer);
            app.txt_expY.Tag = 'txt_expY';
            app.txt_expY.Text = 'Y:';
            app.txt_expZ = uilabel(app.pnl_stack_explorer);
            app.txt_expZ.Tag = 'txt_expZ';
            app.txt_expZ.Text = 'Z:';
            app.btn_find = uibutton(app.pnl_stack_explorer, 'push');
            app.btn_find.ButtonPushedFcn = createCallbackFcn(app, @btn_find_Callback, true);
            app.btn_find.Tag = 'btn_find';
            app.btn_find.Enable = 'off';
            app.btn_find.Text = 'Find';
            app.btn_findl = uibutton(app.pnl_stack_explorer, 'push');
            app.btn_findl.ButtonPushedFcn = createCallbackFcn(app, @btn_findl_Callback, true);
            app.btn_findl.Tag = 'btn_findl';
            app.btn_findl.Enable = 'off';
            app.btn_findl.Text = '<';
            app.btn_findr = uibutton(app.pnl_stack_explorer, 'push');
            app.btn_findr.ButtonPushedFcn = createCallbackFcn(app, @btn_findr_Callback, true);
            app.btn_findr.Tag = 'btn_findr';
            app.btn_findr.Enable = 'off';
            app.btn_findr.Text = '>';
            app.btn_abs_dose = uibutton(app.pnl_dosimetry, 'push');
            app.btn_abs_dose.ButtonPushedFcn = createCallbackFcn(app, @btn_abs_dose_Callback, true);
            app.btn_abs_dose.Tag = 'btn_abs_dose';
            app.btn_abs_dose.Enable = 'off';
            app.btn_abs_dose.Text = 'Calculate Absorbed Dose';
            app.btn_w_dose = uibutton(app.pnl_dosimetry, 'push');
            app.btn_w_dose.ButtonPushedFcn = createCallbackFcn(app, @btn_w_dose_Callback, true);
            app.btn_w_dose.Tag = 'btn_w_dose';
            app.btn_w_dose.Enable = 'off';
            app.btn_w_dose.Text = 'Calculate RBEw Dose';
            app.btn_isoe_dose = uibutton(app.pnl_dosimetry, 'push');
            app.btn_isoe_dose.ButtonPushedFcn = createCallbackFcn(app, @btn_isoe_dose_Callback, true);
            app.btn_isoe_dose.Tag = 'btn_isoe_dose';
            app.btn_isoe_dose.Enable = 'off';
            app.btn_isoe_dose.Text = 'Calculate Isoeffective Dose';
            app.pnl_rois = uipanel(app.pnl_dosimetry);
            app.pnl_rois.Title = 'Regions of interest';
            app.pnl_rois.Tag = 'pnl_rois';
            app.tbl_roi = uitable(app.pnl_rois);
            app.tbl_roi.ColumnName = {'Color'; 'ROI Name'; 'DVH'};
            app.tbl_roi.RowName = '';
            app.tbl_roi.ColumnEditable = [false true true];
            app.tbl_roi.CellEditCallback = createCallbackFcn(app, @tbl_roi_CellEditCallback, true);
            app.tbl_roi.Visible = 'off';
            app.tbl_roi.Tag = 'tbl_roi';
            app.btn_create_dvh = uibutton(app.pnl_dosimetry, 'push');
            app.btn_create_dvh.ButtonPushedFcn = createCallbackFcn(app, @btn_create_dvh_Callback, true);
            app.btn_create_dvh.Tag = 'btn_create_dvh';
            app.btn_create_dvh.Enable = 'off';
            app.btn_create_dvh.Text = 'ROI DVH';
            app.pnl_tir = uipanel(app.pnl_dosimetry);
            app.pnl_tir.Title = 'Irradiation Time';
            app.pnl_tir.Tag = 'pnl_tir';
            app.inp_tir = uieditfield(app.pnl_tir, 'text');
            app.inp_tir.ValueChangedFcn = createCallbackFcn(app, @inp_tir_Callback, true);
            app.inp_tir.Tag = 'inp_tir';
            app.inp_tir.Enable = 'off';
            app.inp_tir.Value = '1';
            app.txt_tir = uilabel(app.pnl_tir);
            app.txt_tir.Tag = 'txt_tir';
            app.txt_tir.Text = 'tir [min]';
            app.bnt_tir_region = uibutton(app.pnl_tir, 'push');
            app.bnt_tir_region.Tag = 'bnt_tir_region';
            app.bnt_tir_region.Enable = 'off';
            app.bnt_tir_region.Text = 'Region';
            app.btn_tir_point = uibutton(app.pnl_tir, 'push');
            app.btn_tir_point.ButtonPushedFcn = createCallbackFcn(app, @btn_tir_point_Callback, true);
            app.btn_tir_point.Tag = 'btn_tir_point';
            app.btn_tir_point.Enable = 'off';
            app.btn_tir_point.Text = 'Point';
            app.btn_utcp = uibutton(app.pnl_dosimetry, 'push');
            app.btn_utcp.ButtonPushedFcn = createCallbackFcn(app, @btn_utcp_Callback, true);
            app.btn_utcp.Tag = 'btn_utcp';
            app.btn_utcp.Enable = 'off';
            app.btn_utcp.Text = 'UTCP';
            app.btn_showMCNPerror = uibutton(app.pnl_dosimetry, 'push');
            app.btn_showMCNPerror.ButtonPushedFcn = createCallbackFcn(app, @btn_showMCNPerror_Callback, true);
            app.btn_showMCNPerror.Tag = 'btn_showMCNPerror';
            app.btn_showMCNPerror.Enable = 'off';
            app.btn_showMCNPerror.Text = 'Relative Error';
            app.pnl_path = uipanel(app.figure1);
            app.pnl_path.Tag = 'pnl_path';
            app.txt_path = uilabel(app.pnl_path);
            app.txt_path.Tag = 'txt_path';
            app.txt_path.Text = 'Path: ';
            app.pnl_status = uipanel(app.figure1);
            app.pnl_status.Tag = 'pnl_status';
            app.txt_status = uilabel(app.pnl_status);
            app.txt_status.Tag = 'txt_status';
            app.txt_status.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dosimetria_hn_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)dosimetria_hn_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end